classdef MouseChecker < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                   matlab.ui.Figure
        MouseProcessingSummaryEditField  matlab.ui.control.EditField
        MouseProcessingSummaryEditFieldLabel  matlab.ui.control.Label
        RowsFoundLabel             matlab.ui.control.Label
        MouseNumberEditField       matlab.ui.control.EditField
        MouseNumberEditFieldLabel  matlab.ui.control.Label
        CheckMosueNumbersButton    matlab.ui.control.Button
        SaveDataFileButton         matlab.ui.control.Button
        LoadDataFileButton         matlab.ui.control.Button
        UITable2                   matlab.ui.control.Table
        UITable                    matlab.ui.control.Table
    end

    
    properties (Access = private)
        DataFile %Path of the DataFile
        Loaded % Datafile present
        Data % Description
        MouseSummary
        MouseSummaryNames
    end
    
    methods (Access = private)
        
        function UpdateMouseSummary(app,fname)
                fname = 'https://docs.google.com/spreadsheets/d/1nrsuo-wz-zq6M1QEZ6RbX9_aMvbypPw8/gviz/tq?tqx=out:csv&sheet=All mice';
                app.MouseSummary = webread(fname);
                app.MouseSummary = app.MouseSummary(:,1:5);
                Vars = {'Group','MouseNumber','Gender','Housing','Genotype'};
                app.MouseSummary.Properties.VariableNames = Vars;

                LastRow = find(strcmp(app.MouseSummary.MouseNumber,'Do not use this row'));
                LastRow = LastRow(4);
                app.MouseSummary = app.MouseSummary(1:LastRow,:);

                EmptyRows = zeros(height(app.MouseSummary),1);
                for i = 1:size(app.MouseSummary,1)
                    if ~isempty(app.MouseSummary.Group{i})
                        app.MouseSummary.Group{i} = extractAfter(app.MouseSummary.Group{i},9);
                    elseif isempty(app.MouseSummary.Group{i})
                        app.MouseSummary.Group{i} = app.MouseSummary.Group{i-1,1};
                    end

                    if isempty(app.MouseSummary.MouseNumber{i})
                        EmptyRows(i) = 1;
                    elseif strcmp(app.MouseSummary.MouseNumber{i},'Do not use this row')
                        EmptyRows(i) = 1;
                    else
                        EmptyRows(i) = 0;
                    end

                end
                EmptyRows = logical(EmptyRows);
                app.MouseSummary(EmptyRows,:) = [];

                app.MouseSummaryNames = app.MouseSummary.MouseNumber;

                app.UITable.Data = app.MouseSummary;
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            UpdateMouseSummary(app,'https://docs.google.com/spreadsheets/d/1nrsuo-wz-zq6M1QEZ6RbX9_aMvbypPw8/gviz/tq?tqx=out:csv&sheet=All mice')
        end

        % Button pushed function: LoadDataFileButton
        function LoadDataFileButtonPushed(app, event)
            [f, p] = uigetfile('*.*','Please sellect data file');
            
            if (ischar(p))
               app.DataFile = [p f];
               app.Data= readtable(app.DataFile,"ReadVariableNames",true);

               app.UITable2.Data = app.Data;
               app.UITable2.ColumnName = app.Data.Properties.VariableNames;

               app.Loaded = true;
            end
        end

        % Button pushed function: SaveDataFileButton
        function SaveDataFileButtonPushed(app, event)
            Export = app.UITable2.Data;

            fid = -1;
            while fid==-1
                fid = fopen(app.DataFile,'a');
                if fid==-1
                    msgbox('Unable to save data. Check if file is open and press any key to continue')
                    pause
                else
                    fclose(fid);
                    disp('Saving...')
                    writetable(Export,app.DataFile,'UseExcel',true,'writemode','inplace','Range','B:G')
                    %writecell(Log,app.DataFile,'useExcel',true,'Sheet','Log','WriteMode','overwritesheet')
                end
            end
        end

        % Value changed function: MouseNumberEditField
        function MouseNumberEditFieldValueChanged(app, event)
            Value = app.MouseNumberEditField.Value;
            removeStyle(app.UITable)
            
            if ~isempty(Value)
                Row = find(contains(app.MouseSummaryNames,Value));

                s = uistyle('BackgroundColor',[1 0.6 0.6]);
                addStyle(app.UITable,s,"row",Row);

                Row = Row.';
                app.RowsFoundLabel.Text = ['Rows Found: ' strjoin(string(Row),' ')];
            else
                app.RowsFoundLabel.Text = 'Rows Found: ';
            end

        end

        % Button pushed function: CheckMosueNumbersButton
        function CheckMosueNumbersButtonPushed(app, event)
            if app.Loaded == true

                Names = table2cell(app.Data);
                Names = Names(:,1);

                FindDash = [];
                StoreDash = zeros(height(Names),1);
                StoreName = cell(height(StoreDash),1);
                StoreMouse = cell(height(StoreName),1);


                disp('Finding MouseNumbers...')
                for i = 2:height(Names)
                    FindDash = strfind(Names{i,1},'-');
                    for j = 1:length(FindDash)
                        StoreDash(i,j) = FindDash(j);
                    end
                end
                
                for i = 1:size(StoreDash,1)
                    for j = 1:size(StoreDash,2)
                        if StoreDash(i,j) > 5
                            StoreName(i,j) = extractBetween(Names(i,1),StoreDash(i,j)-5,StoreDash(i,j)+2);
                        end
                    end
                end
                
                for i = 1:size(StoreName,1)
                    for j=1:size(StoreName,2)
                        if ~isempty(StoreName{i,j})
                            if ~isnan(str2double(extractBetween(StoreName{i,j},1,5)))
                                StoreMouse(i,1) = StoreName(i,j);
                                disp(strjoin({'found mouse number ', StoreMouse{i}}))
                            end
                        end
                    end
                end
                
                disp('Handeling singel digit toe numbers...')
                for i = 2:size(StoreMouse,1)
                    if strcmp(extractBetween(StoreMouse{i},8,8),'_')
                        disp('singel digit toe number found...')
                        disp(strjoin({'Converting', StoreMouse{i}}))
                        StoreMouse(i) = join([extractBetween(StoreMouse{i},1,6) '0' extractBetween(StoreMouse{i},7,7)],"");
                        disp(strjoin({'Converted to', StoreMouse{i}}))
                    end
                end
                
                disp('Handeling missing data...')
                MouseSummaryNames = table2cell(app.MouseSummary(1:end,2));
                Mask = cellfun(@ischar,MouseSummaryNames);
                Mask = ~Mask;
                MouseSummaryNames(Mask) = {' '};
                TheChosenOnes(1,:) = {'interpreted mouse number' 'group' 'MouseNumber' 'Sex' 'Housing' 'Genotype' };
                Log(1,:) = {'Log'};
                
                disp('Matching mice...')
                for i = 2:size(StoreMouse,1)
                    Row = contains(MouseSummaryNames,StoreMouse{i});
                    if sum(Row) == 1
                        TheChosenOnes(i,:) = [StoreMouse(i) table2cell(app.MouseSummary(Row,:))];
                        Log(i,1) = cellstr(strjoin({'Mouse ' , StoreMouse{i} , ' has been found'}));
                        disp(cellstr(strjoin({StoreMouse{i} , ' found'})))
                    else
                        Log(i,1) = cellstr(strjoin({'Mouse ' , StoreMouse{i} , ' was not found in the mouseproccesingsummary'}));
                        TheChosenOnes(i,:) = cellstr(strjoin({'Mouse ' , StoreMouse{i} , ' not found'}));
                        disp(cellstr(strjoin({StoreMouse{i} , 'not found'})))
                    end
                end
                
                Mask = cellfun(@ischar,TheChosenOnes);
                Mask(:,:) = ~Mask(:,:);
                TheChosenOnes(Mask) = {'Data missing'};

                app.UITable2.Data = TheChosenOnes;
    
            else
                msgbox('No file is loaded')
            end
        end

        % Value changed function: MouseProcessingSummaryEditField
        function MouseProcessingSummaryEditFieldValueChanged(app, event)
            value = app.MouseProcessingSummaryEditField.Value;
            
            FileID = extractBetween(value,1,72);

        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UITable = uitable(app.UIFigure);
            app.UITable.ColumnName = {'Group'; 'MouseNumber'; 'Gender'; 'Housing'; 'Genotype'};
            app.UITable.RowName = {};
            app.UITable2 = uitable(app.UIFigure);
            app.UITable2.ColumnName = {''; ''; ''; ''};
            app.UITable2.RowName = {};
            app.LoadDataFileButton = uibutton(app.UIFigure, 'push');
            app.LoadDataFileButton.ButtonPushedFcn = createCallbackFcn(app, @LoadDataFileButtonPushed, true);
            app.LoadDataFileButton.Text = 'Load Data File';
            app.SaveDataFileButton = uibutton(app.UIFigure, 'push');
            app.SaveDataFileButton.ButtonPushedFcn = createCallbackFcn(app, @SaveDataFileButtonPushed, true);
            app.SaveDataFileButton.Text = 'Save Data File';
            app.CheckMosueNumbersButton = uibutton(app.UIFigure, 'push');
            app.CheckMosueNumbersButton.ButtonPushedFcn = createCallbackFcn(app, @CheckMosueNumbersButtonPushed, true);
            app.CheckMosueNumbersButton.Text = 'Check Mosue Numbers';
            app.MouseNumberEditFieldLabel = uilabel(app.UIFigure);
            app.MouseNumberEditFieldLabel.Text = 'MouseNumber';
            app.MouseNumberEditField = uieditfield(app.UIFigure, 'text');
            app.MouseNumberEditField.ValueChangedFcn = createCallbackFcn(app, @MouseNumberEditFieldValueChanged, true);
            app.RowsFoundLabel = uilabel(app.UIFigure);
            app.RowsFoundLabel.Text = 'Rows Found:';
            app.MouseProcessingSummaryEditFieldLabel = uilabel(app.UIFigure);
            app.MouseProcessingSummaryEditFieldLabel.Text = 'MouseProcessingSummary';
            app.MouseProcessingSummaryEditField = uieditfield(app.UIFigure, 'text');
            app.MouseProcessingSummaryEditField.ValueChangedFcn = createCallbackFcn(app, @MouseProcessingSummaryEditFieldValueChanged, true);
            app.MouseProcessingSummaryEditField.Value = 'https://docs.google.com/spreadsheets/d/1nrsuo-wz-zq6M1QEZ6RbX9_aMvbypPw8/edit#gid=1215606350';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = MouseChecker

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end