classdef MouseChecker < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                   matlab.ui.Figure
        MouseProcessingSummaryEditField  matlab.ui.control.EditField
        MouseProcessingSummaryEditFieldLabel  matlab.ui.control.Label
        RowsFoundLabel             matlab.ui.control.Label
        MouseNumberEditField       matlab.ui.control.EditField
        MouseNumberEditFieldLabel  matlab.ui.control.Label
        CheckMouseNumbersButton    matlab.ui.control.Button
        SaveDataFileButton         matlab.ui.control.Button
        LoadDataFileButton         matlab.ui.control.Button
        UITable2                   matlab.ui.control.Table
        UITable                    matlab.ui.control.Table
    end

    
    properties (Access = private)
        DataFile %Path of the DataFile
        Loaded % Datafile present
        Data % Description
        MouseSummary
        MouseSummaryNames
    end
    
    methods (Access = private)
        
        function UpdateMouseSummary(app)
                fname = 'https://docs.google.com/spreadsheets/d/1nrsuo-wz-zq6M1QEZ6RbX9_aMvbypPw8/gviz/tq?tqx=out:csv&sheet=All mice';
                app.MouseSummary = webread(fname);
                app.MouseSummary = app.MouseSummary(:,1:5);
                Vars = {'Group','MouseNumber','Gender','Housing','Genotype'};
                app.MouseSummary.Properties.VariableNames = Vars;

                LastRow = find(strcmp(app.MouseSummary.MouseNumber,'Do not use this row'));
                LastRow = LastRow(4);
                app.MouseSummary = app.MouseSummary(1:LastRow,:);

                EmptyRows = zeros(height(app.MouseSummary),1);
                for i = 1:size(app.MouseSummary,1)
                    if ~isempty(app.MouseSummary.Group{i})
                        app.MouseSummary.Group{i} = extractAfter(app.MouseSummary.Group{i},9);
                    elseif isempty(app.MouseSummary.Group{i})
                        app.MouseSummary.Group{i} = app.MouseSummary.Group{i-1,1};
                    end

                    if isempty(app.MouseSummary.MouseNumber{i})
                        EmptyRows(i) = 1;
                    elseif strcmp(app.MouseSummary.MouseNumber{i},'Do not use this row')
                        EmptyRows(i) = 1;
                    else
                        EmptyRows(i) = 0;
                    end

                end
                EmptyRows = logical(EmptyRows);
                app.MouseSummary(EmptyRows,:) = [];

                app.MouseSummaryNames = app.MouseSummary.MouseNumber;

                app.UITable.Data = app.MouseSummary;
        end
        
        function CheckMouseNumbers(app)
                J = 1;
                Log = table('size',[1 3],'VariableTypes',["string", "string", "string"],'VariableNames',{'MouseNumber','MouseSummary','YMaze_Data'});
                for i = 1:height(app.Data)
                    Row = strcmp(extractAfter(app.Data.MouseNumber(i),'MI'),extractAfter(app.MouseSummary.MouseNumber,'MI'));
                    MouseInfo = app.MouseSummary(Row,:);
                    if sum(Row) == 0
                        s = uistyle('BackgroundColor','Red');
                        addStyle(app.UITable2,s,"row",i);
                        Log.MouseNumber(J) = app.Data.MouseNumber(i);
                        J = J+1;
                    else
                        if strcmpi(MouseInfo.Group,app.Data.Group(i))
                            s = uistyle('BackgroundColor','green');
                            addStyle(app.UITable2,s,"cell",[i 1]);
                        else
                            s = uistyle('BackgroundColor','Red');
                            addStyle(app.UITable2,s,"cell",[i 1]);
                            Log.MouseNumber(J) = app.Data.MouseNumber(i);
                            Log.MouseSummary(J) = MouseInfo.Group;
                            Log.YMaze_Data(J) = app.Data.Group(i);
                            J = J+1;
                        end
                        if strcmpi(MouseInfo.Gender,app.Data.Sex(i))
                            s = uistyle('BackgroundColor','green');
                            addStyle(app.UITable2,s,"cell",[i 2]);
                        else
                            s = uistyle('BackgroundColor','Red');
                            addStyle(app.UITable2,s,"cell",[i 2]);
                            Log.MouseNumber(J) = app.Data.MouseNumber(i);
                            Log.MouseSummary(J) = MouseInfo.Gender;
                            Log.YMaze_Data(J) = app.Data.Sex(i);
                            J = J+1;
                        end
                        if strcmpi(MouseInfo.Housing,app.Data.Housing(i))
                            s = uistyle('BackgroundColor','green');
                            addStyle(app.UITable2,s,"cell",[i 3]);
                        else
                            s = uistyle('BackgroundColor','Red');
                            addStyle(app.UITable2,s,"cell",[i 3]);
                            Log.MouseNumber(J) = app.Data.MouseNumber(i);
                            Log.MouseSummary(J) = MouseInfo.Housing;
                            Log.YMaze_Data(J) = app.Data.Housing(i);
                            J = J+1;
                        end
                        if strcmpi(MouseInfo.Genotype,app.Data.Genotype(i))
                            s = uistyle('BackgroundColor','green');
                            addStyle(app.UITable2,s,"cell",[i 4]);
                        else
                            s = uistyle('BackgroundColor','Red');
                            addStyle(app.UITable2,s,"cell",[i 4]);
                            Log.MouseNumber(J) = app.Data.MouseNumber(i);
                            Log.MouseSummary(J) = MouseInfo.Genotype;
                            Log.YMaze_Data(J) = app.Data.Genotype(i);
                            J = J+1;
                        end
                        s = uistyle('BackgroundColor','green');
                        addStyle(app.UITable2,s,"cell",[i 5]);
                    end
                end
                Fig = uifigure('Name','Log');
                Text = uitable(Fig,'Data',Log);
                Text.Position = [10 10 540 400];
                msgbox('Done!')
        end
        
        function Output = FindMouseNumbers(~,Names)

                StoreDash = zeros(height(Names),1);
                StoreName = cell(height(StoreDash),1);
                StoreMouse = cell(height(StoreName),1);
                        
                disp('Finding MouseNumbers...')
                for i = 1:height(Names)
                    FindDash = strfind(Names{i,1},'-');
                    for j = 1:length(FindDash)
                        StoreDash(i,j) = FindDash(j);
                    end
                end
                
                for i = 1:size(StoreDash,1)
                    for j = 1:size(StoreDash,2)
                        if StoreDash(i,j) > 5
                            StoreName(i,j) = extractBetween(Names(i,1),StoreDash(i,j)-5,StoreDash(i,j)+2);
                        end
                    end
                end
                
                for i = 1:size(StoreName,1)
                    for j=1:size(StoreName,2)
                        if ~isempty(StoreName{i,j})
                            if ~isnan(str2double(extractBetween(StoreName{i,j},1,5)))
                                StoreMouse(i,1) = StoreName(i,j);
                                disp(strjoin({'found mouse number ', StoreMouse{i}}))
                            end
                        end
                    end
                end
                
                disp('Handeling singel digit toe numbers...')
                for i = 1:size(StoreMouse,1)
                    if ~isempty(StoreMouse{i})
                        if isnan(str2double(extractBetween(StoreMouse{i},7,8)))
                            disp('singel digit toe number found...')
                            disp(strjoin({'Converting', StoreMouse{i}}))
                            StoreMouse(i) = join([extractBetween(StoreMouse{i},1,6) '0' extractBetween(StoreMouse{i},7,7)],"");
                            disp(strjoin({'Converted to', StoreMouse{i}}))
                        end
                    end
                end

                Output = StoreMouse;
        end

        function MatchingMice(app,Names)

                disp('Handeling missing data...')
                MouseSummaryData = table2cell(app.MouseSummary(1:end,2));
                Mask = cellfun(@ischar,MouseSummaryData);
                Mask = ~Mask;
                MouseSummaryData(Mask) = {' '};


                var = {'interpreted mouse number' 'group' 'MouseNumber' 'Sex' 'Housing' 'Genotype' };
                Log(1,:) = {'Log'};

                disp('Matching mice...')
                for i = 1:size(Names,1)
                    Row = contains(MouseSummaryData,Names{i});
                    if sum(Row) == 1
                        TheChosenOnes(i,:) = [Names(i) table2cell(app.MouseSummary(Row,:))];
                        Log(i,1) = cellstr(strjoin({'Mouse ' , Names{i} , ' has been found'}));
                        disp(cellstr(strjoin({Names{i} , ' found'})))
                    else
                        Log(i,1) = cellstr(strjoin({'Mouse ' , Names{i} , ' was not found in the mouseproccesingsummary'}));
                        TheChosenOnes(i,:) = cellstr(strjoin({'Mouse ' , Names{i} , ' not found'}));
                        disp(cellstr(strjoin({Names{i} , 'not found'})))
                    end
                end
                
                Mask = cellfun(@ischar,TheChosenOnes);
                Mask(:,:) = ~Mask(:,:);
                TheChosenOnes(Mask) = {'Data missing'};


                TheChosenOnes = cell2table(TheChosenOnes(1:end,:),"VariableNames",var);
                Output = app.Data(:,1);
                Output.Properties.VariableNames{1} = app.Data.Properties.VariableNames{1};
                Output(:,2:7) = TheChosenOnes;
                Output.Properties.VariableNames(2:7) = TheChosenOnes.Properties.VariableNames(1:6);
                Output(:,8:45) = app.Data(:,2:39);
                Output.Properties.VariableNames(8:45) = app.Data.Properties.VariableNames(2:39);


                app.UITable2.Data = Output;
                app.UITable2.ColumnName = Output.Properties.VariableNames;
                
                msgbox('Done!')
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            UpdateMouseSummary(app)
        end

        % Button pushed function: LoadDataFileButton
        function LoadDataFileButtonPushed(app, event)
            [f, p] = uigetfile('*.*','Please sellect data file');
            
            if (ischar(p))
               app.DataFile = [p f];
               if contains(f,"OF") || contains(f,"EPM") || contains(f,"SC")
                   app.Data= readtable(app.DataFile,"ReadVariableNames",true);
                   app.UITable2.Data = app.Data;
                   app.UITable2.ColumnName = app.Data.Properties.VariableNames;
                   app.Loaded = 'Optimouse';
               elseif contains(f,'Y-maze')
                   app.Data = readtable(app.DataFile,UseExcel=true);
                   app.Data = app.Data(4:(sum(find(any(strcmp(app.Data.MouseNumber,''),2),4))-7),(1:195));
                   app.Data = app.Data(:,1:5);
                   for i = 1:height(app.Data)                                                      
                        if strcmp(app.Data.Group(i),'')                                            
                            app.Data.Group(i) = app.Data.Group(i-1);                                   
                        end
                   end
                   app.UITable2.Data = app.Data;
                   app.UITable2.ColumnName = {'Group','Gender','Housing','Genotype','MouseNumber'};
                   app.Loaded = 'Y-maze';
               end
            end
        end

        % Button pushed function: SaveDataFileButton
        function SaveDataFileButtonPushed(app, event)
            if ~isempty(app.UITable2.Data)
                Export = app.UITable2.Data;

                fid = fopen(app.DataFile,'a');
                if fid==-1
                    msgbox('Unable to save data. Check if file is open and press any key to continue')
                    pause
                else
                    fclose(fid);
                    disp('Saving...')
                    writetable(Export,app.DataFile,'UseExcel',true,"Sheet",'MouseChecker','WriteVariableNames',true,'WriteMode','overwritesheet')
                    %writecell(Log,app.DataFile,'useExcel',true,'Sheet','Log','WriteMode','overwritesheet')
                    msgbox('File Saved')
                end

            else
                msgbox('No File Loaded')
            end
        end

        % Value changed function: MouseNumberEditField
        function MouseNumberEditFieldValueChanged(app, event)
            Value = app.MouseNumberEditField.Value;
            removeStyle(app.UITable)
            
            if ~isempty(Value)
                Row = find(contains(app.MouseSummaryNames,Value));

                s = uistyle('BackgroundColor',[1 0.6 0.6]);
                addStyle(app.UITable,s,"row",Row);

                Row = Row.';
                app.RowsFoundLabel.Text = ['Rows Found: ' strjoin(string(Row),' ')];
            else
                app.RowsFoundLabel.Text = 'Rows Found: ';
            end

        end

        % Button pushed function: CheckMouseNumbersButton
        function CheckMouseNumbersButtonPushed(app, event)
            if strcmp(app.Loaded,'Optimouse')
                Numbers = FindMouseNumbers(app,table2cell(app.Data(:,1)));
                MatchingMice(app,Numbers)

            elseif strcmp(app.Loaded,'Y-maze')
                CheckMouseNumbers(app)
            else
                msgbox('No file is loaded')
            end
            
        end

        % Value changed function: MouseProcessingSummaryEditField
        function MouseProcessingSummaryEditFieldValueChanged(app, event)
            value = app.MouseProcessingSummaryEditField.Value;
            
            FileID = extractBetween(value,1,72);

        end

        % Callback function
        function CompairFoldersButtonPushed(app, event)
%               Name1 = uigetdir;
%               Name2 = uigetdir;
% 
%               visdiff(Name1,Name2)
% 
%             Results = dir(uigetdir);
%             Results = {Results.name};
%             Results = Results(~strncmp(Results, '.', 1));
%             Results = transpose(Results(~strncmp(Results, '~', 1)));
%             for i = 1:height(Results)
%                 if ~contains(Results{i},'-')
%                     Results{i} = strrep(Results{i},'_','-');
%                 end
%             end
%             Results = FindMouseNumbers(app,Results);
% 
%             DataFolder = dir(uigetdir);
%             DataFolder = {DataFolder.name};
%             DataFolder = DataFolder(~strncmp(DataFolder, '.', 1));
%             DataFolder = transpose(DataFolder(~strncmp(DataFolder, '~', 1)));
%             for i = 1:height(DataFolder)
%                 if ~contains(DataFolder{i},'-')
%                     DataFolder{i} = strrep(DataFolder{i},'_','-');
%                 end
%             end
%             DataFolder = FindMouseNumbers(app,DataFolder);
% 
%             Output = app.MouseSummary.MouseNumber;
%             Rows = false(1,height(app.MouseSummary.MouseNumber));
% 
%             for i = 1:height(Results)
%                 if~isempty(Results{i})
%                     Row = contains(Output(:,1),Results{i});
%                     Output(Row,2) = Results(i);
%                     Rows(Row,1) = true;
%                 end
%             end
% 
%             for i = 1:height(DataFolder)
%                 if~isempty(Results{i})
%                     Row = contains(Output(:,1),DataFolder{i});
%                     Output(Row,3) = DataFolder(i);
%                     Rows(Row,1) = true;
%                 end
%             end
% 
%             Output = Output(Rows,:);
% 
%             app.UITable2.Data = Output;
%             app.UITable2.ColumnName = {'MouseSummary','Results','DataFolder'};
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UITable = uitable(app.UIFigure);
            app.UITable.ColumnName = {'Group'; 'MouseNumber'; 'Gender'; 'Housing'; 'Genotype'};
            app.UITable.RowName = {};
            app.UITable2 = uitable(app.UIFigure);
            app.UITable2.ColumnName = {''; ''; ''; ''};
            app.UITable2.RowName = {};
            app.LoadDataFileButton = uibutton(app.UIFigure, 'push');
            app.LoadDataFileButton.ButtonPushedFcn = createCallbackFcn(app, @LoadDataFileButtonPushed, true);
            app.LoadDataFileButton.Text = 'Load Data File';
            app.SaveDataFileButton = uibutton(app.UIFigure, 'push');
            app.SaveDataFileButton.ButtonPushedFcn = createCallbackFcn(app, @SaveDataFileButtonPushed, true);
            app.SaveDataFileButton.Text = 'Save Data File';
            app.CheckMouseNumbersButton = uibutton(app.UIFigure, 'push');
            app.CheckMouseNumbersButton.ButtonPushedFcn = createCallbackFcn(app, @CheckMouseNumbersButtonPushed, true);
            app.CheckMouseNumbersButton.Text = 'Check Mouse Numbers';
            app.MouseNumberEditFieldLabel = uilabel(app.UIFigure);
            app.MouseNumberEditFieldLabel.Text = 'MouseNumber';
            app.MouseNumberEditField = uieditfield(app.UIFigure, 'text');
            app.MouseNumberEditField.ValueChangedFcn = createCallbackFcn(app, @MouseNumberEditFieldValueChanged, true);
            app.RowsFoundLabel = uilabel(app.UIFigure);
            app.RowsFoundLabel.Text = 'Rows Found:';
            app.MouseProcessingSummaryEditFieldLabel = uilabel(app.UIFigure);
            app.MouseProcessingSummaryEditFieldLabel.Text = 'MouseProcessingSummary';
            app.MouseProcessingSummaryEditField = uieditfield(app.UIFigure, 'text');
            app.MouseProcessingSummaryEditField.ValueChangedFcn = createCallbackFcn(app, @MouseProcessingSummaryEditFieldValueChanged, true);
            app.MouseProcessingSummaryEditField.Value = 'https://docs.google.com/spreadsheets/d/1nrsuo-wz-zq6M1QEZ6RbX9_aMvbypPw8/edit#gid=1215606350';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = MouseChecker

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end